classdef AppforMATLAB2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        Label                     matlab.ui.control.Label
        samplingRate              matlab.ui.control.NumericEditField
        Label_2                   matlab.ui.control.Label
        samplingExtent            matlab.ui.control.NumericEditField
        InputTable                matlab.ui.control.Table
        Button_init               matlab.ui.control.Button
        Button_autocorrelation    matlab.ui.control.Button
        Button_showSIgnal1        matlab.ui.control.Button
        Button_showFeature        matlab.ui.control.StateButton
        Panel_2                   matlab.ui.container.Panel
        Label_3                   matlab.ui.control.Label
        outputBelongs             matlab.ui.control.EditField
        EditField_2Label          matlab.ui.control.Label
        outputVpp                 matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        EditField_3Label          matlab.ui.control.Label
        outputVp                  matlab.ui.control.NumericEditField
        EditField_4Label          matlab.ui.control.Label
        outputEffectiveValue      matlab.ui.control.NumericEditField
        EditField_5Label          matlab.ui.control.Label
        outputAverageValue        matlab.ui.control.NumericEditField
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        Label_5                   matlab.ui.control.Label
        Lamp_Card                 matlab.ui.control.Lamp
        Switch_Card               matlab.ui.control.Switch
        Tab_3                     matlab.ui.container.Tab
        Tab_4                     matlab.ui.container.Tab
        TabGroup2                 matlab.ui.container.TabGroup
        Tab_2                     matlab.ui.container.Tab
        UIAxes_main               matlab.ui.control.UIAxes
        Tab_7                     matlab.ui.container.Tab
        UIAxes_signal2            matlab.ui.control.UIAxes
        Panel_3                   matlab.ui.container.Panel
        UIAxes_powerSpectrum      matlab.ui.control.UIAxes
        UIAxes_amplitudeSpectrum  matlab.ui.control.UIAxes
        UIAxes_phaseSpectrum      matlab.ui.control.UIAxes
        TabGroup3                 matlab.ui.container.TabGroup
        Tab_5                     matlab.ui.container.Tab
        UIAxes_realSpectrum       matlab.ui.control.UIAxes
        Tab_6                     matlab.ui.container.Tab
        UIAxes_imaginarySpectrum  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Fs % 采样频率
        N %采样长度
        T %时间长度
        
        Signal1 %自制信号
        Signal2Channel1 %采集卡信号
        Signal2Channel2
        Signal3 %特殊信号
        SuperImposedSignal %叠加信号
        
        X %横坐标
        CardState = 0;
        
        xLimit %横坐标范围
        
        isShowProperties % Description
        CardData % Description
    end

    methods (Access = private)
    
        function getSignal1(app)
            app.T = app.N/app.Fs;
            D = app.InputTable.Data;
            m =size(D,1);
            y = D(1,2)*sin(2*pi*D(1,1)*app.X+D(1,3));
            if m>1
                for i = 2:m
                    y = y + D(i,2)*sin(2*pi*D(i,1)*app.X+D(i,3));
                end
            end
            app.Signal1.Y = y;
            app.Signal1 = refreshProperties(app,app.Signal1);
        end
        
        function getSignal2(app)
            app.CardData=USBDAQ_readtwoCH(app.N,app.Fs,1,5);
            app.Signal2Channel1=app.CardData(:,1);
            app.Signal2Channel1=app.CardData(:,2);
            showSignal2(app);
            app.Signal2Channel1 = refreshProperties(app,app.Signal2Channel1);
            app.Signal2Channel2 = refreshProperties(app,app.Signal2Channel2);
            
        end
        %% 分析谱图
        function spectrum = getAmplitudeSpectrum(app,yy)
            spectrum = 2*sqrt(yy.*conj(yy))/app.N; %求An
            spectrum(1) = spectrum(1)/2;
            spectrum = spectrum(1:app.N/2);
        end
        
        function spectrum = getPhaseSpectrum(app,yy)
            spectrum = 2*angle(yy(1:app.N/2))*180/pi;
        end
        
        function specturm = getPowerSpectrum(app,yy)
            specturm = 2*sqrt(yy.*conj(yy))/app.N;
            specturm(1) = specturm(1)/2;
            specturm = specturm.^2;
            specturm = specturm(1:app.N/2);
        end
        
        function spectrum = getImaginaryFrequency(app,yy)
            spectrum = real(yy(1:app.N/2));
        end
        
        function spectrum = getRealFrequency(app,yy)
            spectrum = real(yy(1:app.N/2));
        end
        
        function Signal = refreshProperties(app,Signal)
            y = Signal.Y;
            Signal.Vp = max(y);
            Signal.Vv = min(y);
            Signal.Vpp =  Signal.Vp - Signal.Vv;
            Signal.AVG = mean(y);
            Signal.EffectiveValue = norm(y)/sqrt(length(y));
            yy = fft(Signal.Y,app.N);
            Signal.amplitudeSpectrum = getAmplitudeSpectrum(app,yy);
            Signal.phaseSpectrum = getPhaseSpectrum(app,yy);
            Signal.powerSpectrum = getPowerSpectrum(app,yy);
            Signal.imaginaryFrequency = getImaginaryFrequency(app,yy);
            Signal.realFrequecncy = getRealFrequency(app,yy);
        end
        
        %%辅助函数
        function refreshT(app)
            app.T = app.N/app.Fs;
        end
        
        %%辅助绘图软件
        function plotSpectrum(app,Signal)
            plotAmplitudeSpectrum(app,Signal);
            plotPhaseSpectrum(app,Signal);
            plotPowerSpectrum(app,Signal);
            plotImaginaryFrequency(app,Signal);
            plotRealFrequency(app,Signal);  
        end
        
        function plotAmplitudeSpectrum(app,Signal)
            f = linspace(0,app.Fs/2,app.N/2);
            app.UIAxes_amplitudeSpectrum.XLim = [0 app.xLimit];
            plot(app.UIAxes_amplitudeSpectrum,f,Signal.amplitudeSpectrum);
            
        end
        
        function plotPhaseSpectrum(app,Signal)
            f = linspace(0,app.Fs/2,app.N/2);
            app.UIAxes_phaseSpectrum.XLim = [0 app.xLimit];
            plot(app.UIAxes_phaseSpectrum,f,Signal.phaseSpectrum);
        end
        
        function plotPowerSpectrum(app,Signal)
            f = linspace(0,app.Fs/2,app.N/2);
            app.UIAxes_powerSpectrum.XLim = [0 app.xLimit];
            plot(app.UIAxes_powerSpectrum,f,Signal.powerSpectrum);
        end
        
        function plotImaginaryFrequency(app,Signal)
            f = linspace(0,app.Fs/2,app.N/2);
            app.UIAxes_imaginarySpectrum.XLim = [0 app.xLimit];
            plot(app.UIAxes_imaginarySpectrum,f,Signal.imaginaryFrequency);
        end
        
        function plotRealFrequency(app,Signal)
            f = linspace(0,app.Fs/2,app.N/2);
            app.UIAxes_imaginarySpectrum.XLim = [0 app.xLimit];
            plot(app.UIAxes_imaginarySpectrum,f,Signal.realFrequecncy);
        end
        
        function plotProperties(app,Axex,Signal)
            if app.isShowProperties
                x = [0,app.T];
                hold (Axex,'on');
                plot(Axex,x,Signal.Vp.*ones(1,2));
                plot(Axex,x,Signal.Vv.*ones(1,2));
                plot(Axex,x,Signal.AVG.*ones(1,2));
                plot(Axex,x,Signal.EffectiveValue.*ones(1,2));
                hold (Axex,'off');
            end
        end
        
        %%输出参数
        function outputProperties(app,Signal,Name)
            app.outputAverageValue.Value = Signal.AVG;
            app.outputVp.Value = Signal.Vp;
            app.outputEffectiveValue.Value = Signal.EffectiveValue;
            app.outputVpp.Value = Signal.Vpp;
            app.outputBelongs.Value = Name;
        end
        
        %%输出信号1
        function showSignal1(app)
            getSignal1(app);
            plotSignal1(app);
            outputProperties(app,app.Signal1,'自制信号')
            plotSpectrum(app,app.Signal1);
        end
        
        function plotSignal1(app)
            plot(app.UIAxes_main,app.X,app.Signal1.Y);
            plotProperties(app,app.UIAxes_main,app.Signal1);
        end
        
        %%输出信号2
        function showSignal2(app)
            getSignal2(app);
            plotSignal2(app);
            outputProperties(app,app.Signal2Channel1,"采集卡信号")
            %plotSpectrum(app,app.Signal2Channel1);
            
        end
        
        function plotSignal2(app)
            hold(app.UIAxes_signal2,"on");
            plot(app.UIAxes_signal2,app.X,app.Signal2Channel1.Y);
            drownow;
            plot(app.UIAxes_signal2,app.X,app.Signal2Channel2.Y);
            drawnow
            hold(app.UIAxes_signal2,"off");
            %plotProperties(app,app.UIAxes_signal2,app.Signal2Channel1);
        end


        function counter = TDZCD(app,y)
        %Time domain zero crossing detection时域过零检测算法
            counter =0;
            for i=1:app.N-1
                if y(i)*y(i+1)< 0
                    counter = counter+1;
                end
            end
            
        end

        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            a = [50,2,pi/6;
                200,1,pi/3;
                100,3,pi/2];
            
            app.samplingRate.Value = 5120;
            app.Fs = app.samplingRate.Value;
            app.samplingExtent.Value = 1000;
            app.N = app.samplingExtent.Value;
            refreshT(app);
            
            app.InputTable.Data  = a;
            app.X = linspace(0,app.T,app.N);
            app.xLimit = max(app.InputTable.Data(:,1))*1.5;
            
            app.Switch_Card.ItemsData = {0,1};
            %app.whiteNoiseA.Value = 1;
            app.Lamp_Card.Color = 'red';
            
            showSignal1(app);
        end

        % Button pushed function: Button_autocorrelation
        function Button_autocorrelationPushed(app, event)
            app.isShowProperties = app.Button_showFeature.Value;
            plotSignal1(app);
        end

        % Button pushed function: Button_init
        function Button_initPushed(app, event)
            startupFcn(app);
        end

        % Value changed function: Button_showFeature
        function Button_showFeatureValueChanged(app, event)
            app.isShowProperties = app.Button_showFeature.Value;
            plotSignal1(app);
        end

        % Button pushed function: Button_showSIgnal1
        function Button_showSIgnal1Pushed(app, event)
            showSignal1(app);
        end

        % Cell edit callback: InputTable
        function InputTableCellEdit(app, event)
            showSignal1(app)
        end

        % Value changed function: Switch_Card
        function Switch_CardValueChanged(app, event)
            value = app.Switch_Card.Value;
            if (value == 0)
                app.Lamp_Card.Color = 'red';
                app.CardState = 0;
                USBDAQ_closecard;
            else
                app.CardState=USBDAQ_opencard;
                if app.CardState==1
                    disp('采集卡开启成功');
                else
                    disp('采集卡开启失败');
                end
                %循环控制，以达到实时更新
                while(app.CardState)
                    showSignal2(app);
                 
                    pause(0.2);
                end
            end
                
        end

        % Value changed function: samplingExtent
        function samplingExtentValueChanged(app, event)
            app.N = app.samplingExtent.Value;
            refreshT(app);
        end

        % Value changed function: samplingRate
        function samplingRateValueChanged(app, event)
            app.Fs = app.samplingRate.Value;
            refreshT(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '基本信息';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '采样频率';
            app.samplingRate = uieditfield(app.Panel, 'numeric');
            app.samplingRate.ValueChangedFcn = createCallbackFcn(app, @samplingRateValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '采样长度';
            app.samplingExtent = uieditfield(app.Panel, 'numeric');
            app.samplingExtent.ValueChangedFcn = createCallbackFcn(app, @samplingExtentValueChanged, true);
            app.InputTable = uitable(app.Panel);
            app.InputTable.ColumnName = {'频率(Hz)'; '幅值'; '初相位'};
            app.InputTable.RowName = {};
            app.InputTable.CellEditCallback = createCallbackFcn(app, @InputTableCellEdit, true);
            app.Button_init = uibutton(app.Panel, 'push');
            app.Button_init.ButtonPushedFcn = createCallbackFcn(app, @Button_initPushed, true);
            app.Button_init.Text = '初始化';
            app.Button_autocorrelation = uibutton(app.Panel, 'push');
            app.Button_autocorrelation.ButtonPushedFcn = createCallbackFcn(app, @Button_autocorrelationPushed, true);
            app.Button_autocorrelation.Text = '自相关分析';
            app.Button_showSIgnal1 = uibutton(app.Panel, 'push');
            app.Button_showSIgnal1.ButtonPushedFcn = createCallbackFcn(app, @Button_showSIgnal1Pushed, true);
            app.Button_showSIgnal1.Text = '绘制波形';
            app.Button_showFeature = uibutton(app.Panel, 'state');
            app.Button_showFeature.ValueChangedFcn = createCallbackFcn(app, @Button_showFeatureValueChanged, true);
            app.Button_showFeature.Text = '绘制特征';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '特征';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '信号';
            app.outputBelongs = uieditfield(app.Panel_2, 'text');
            app.EditField_2Label = uilabel(app.Panel_2);
            app.EditField_2Label.Text = '峰峰值';
            app.outputVpp = uieditfield(app.Panel_2, 'numeric');
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '的特征';
            app.EditField_3Label = uilabel(app.Panel_2);
            app.EditField_3Label.Text = '峰值';
            app.outputVp = uieditfield(app.Panel_2, 'numeric');
            app.EditField_4Label = uilabel(app.Panel_2);
            app.EditField_4Label.Text = '有效值';
            app.outputEffectiveValue = uieditfield(app.Panel_2, 'numeric');
            app.EditField_5Label = uilabel(app.Panel_2);
            app.EditField_5Label.Text = '平均值';
            app.outputAverageValue = uieditfield(app.Panel_2, 'numeric');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '采集卡';
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '采集卡状态';
            app.Lamp_Card = uilamp(app.Tab);
            app.Switch_Card = uiswitch(app.Tab, 'slider');
            app.Switch_Card.ValueChangedFcn = createCallbackFcn(app, @Switch_CardValueChanged, true);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '白噪声';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '方波';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '自制信号';
            app.UIAxes_main = uiaxes(app.Tab_2);
            xlabel(app.UIAxes_main, 'X')
            ylabel(app.UIAxes_main, 'Y')
            app.Tab_7 = uitab(app.TabGroup2);
            app.Tab_7.Title = '采集信号';
            app.UIAxes_signal2 = uiaxes(app.Tab_7);
            xlabel(app.UIAxes_signal2, 'X')
            ylabel(app.UIAxes_signal2, 'Y')
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '谱分析';
            app.UIAxes_powerSpectrum = uiaxes(app.Panel_3);
            title(app.UIAxes_powerSpectrum, '功率谱')
            xlabel(app.UIAxes_powerSpectrum, 'P')
            ylabel(app.UIAxes_powerSpectrum, 'F(Hz)')
            app.UIAxes_amplitudeSpectrum = uiaxes(app.Panel_3);
            title(app.UIAxes_amplitudeSpectrum, '幅值谱')
            xlabel(app.UIAxes_amplitudeSpectrum, 'F(Hz)')
            ylabel(app.UIAxes_amplitudeSpectrum, 'An')
            app.UIAxes_phaseSpectrum = uiaxes(app.Panel_3);
            title(app.UIAxes_phaseSpectrum, '相位谱')
            xlabel(app.UIAxes_phaseSpectrum, 'F(Hz)')
            ylabel(app.UIAxes_phaseSpectrum, 'Y')
            app.TabGroup3 = uitabgroup(app.Panel_3);
            app.Tab_5 = uitab(app.TabGroup3);
            app.Tab_5.Title = '实频率谱';
            app.UIAxes_realSpectrum = uiaxes(app.Tab_5);
            xlabel(app.UIAxes_realSpectrum, 'F(Hz)')
            ylabel(app.UIAxes_realSpectrum, 'Re')
            app.Tab_6 = uitab(app.TabGroup3);
            app.Tab_6.Title = '虚频谱';
            app.UIAxes_imaginarySpectrum = uiaxes(app.Tab_6);
            xlabel(app.UIAxes_imaginarySpectrum, 'F(Hz)')
            ylabel(app.UIAxes_imaginarySpectrum, 'Im')
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppforMATLAB2017

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end