classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        InputTable                    matlab.ui.control.Table
        Label                         matlab.ui.control.Label
        Button_init                   matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        samplingRate                  matlab.ui.control.NumericEditField
        EditField_2Label              matlab.ui.control.Label
        samplingExtent                matlab.ui.control.NumericEditField
        Button_autocorrelation        matlab.ui.control.Button
        TabGroup3                     matlab.ui.container.TabGroup
        Tab_signal2Analysis           matlab.ui.container.Tab
        Label_7                       matlab.ui.control.Label
        Lamp_Card                     matlab.ui.control.Lamp
        Switch_Card                   matlab.ui.control.Switch
        Tab_5                         matlab.ui.container.Tab
        Button_getWhiteNoise          matlab.ui.control.Button
        Label_8                       matlab.ui.control.Label
        whiteNoiseA                   matlab.ui.control.NumericEditField
        whiteNoiseAutocorrelation     matlab.ui.control.Button
        superpositionWithSignal1      matlab.ui.control.Button
        correlationWithSignal1        matlab.ui.control.Button
        superpositionAutocorrelation  matlab.ui.control.Button
        Tab_signal3Analysis           matlab.ui.container.Tab
        getSquare                     matlab.ui.control.Button
        Label_9                       matlab.ui.control.Label
        squareA                       matlab.ui.control.NumericEditField
        Label_10                      matlab.ui.control.Label
        squareF                       matlab.ui.control.NumericEditField
        Label_11                      matlab.ui.control.Label
        squareDuty                    matlab.ui.control.NumericEditField
        Label_12                      matlab.ui.control.Label
        squareFtested                 matlab.ui.control.NumericEditField
        U201810832Label               matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        showVpp                       matlab.ui.control.NumericEditField
        EditField_2Label_2            matlab.ui.control.Label
        showVp                        matlab.ui.control.NumericEditField
        Label_5                       matlab.ui.control.Label
        showEffectiveValue            matlab.ui.control.NumericEditField
        Label_6                       matlab.ui.control.Label
        showAverageValue              matlab.ui.control.NumericEditField
        Button_showFeature            matlab.ui.control.StateButton
        CenterPanel                   matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        UIAxes_main                   matlab.ui.control.UIAxes
        Tab_2                         matlab.ui.container.Tab
        UIAxes_signal2                matlab.ui.control.UIAxes
        Tab_6                         matlab.ui.container.Tab
        UIAxes_superposition          matlab.ui.control.UIAxes
        UIAxes_result                 matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        UIAxes_powerSpectrum          matlab.ui.control.UIAxes
        UIAxes_amplitudeSpectrum      matlab.ui.control.UIAxes
        TabGroup2                     matlab.ui.container.TabGroup
        Tab_3                         matlab.ui.container.Tab
        UIAxes_realSpectrum           matlab.ui.control.UIAxes
        Tab_4                         matlab.ui.container.Tab
        UIAxes_virtualSpectrum        matlab.ui.control.UIAxes
        UIAxes_phaseSpectrum          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)

        Fs% 采样频率
        N % 采样长度
        T %时间长度

        Signal1
        Signal2
        
        isShowFeature % Description

        CardState % 采集卡的状态；
        CardData % 采集卡的信息
        Signal3 % 白噪声等信号
        X % 横坐标
        Superposition % 叠加信号
        
    end
    
    methods (Access = private)
        function Show(app)
            %%设置坐标范围
            xLimit = max(app.InputTable.Data(:,1))*1.5;
            app.UIAxes_realSpectrum.XLim = [0 xLimit];
            app.UIAxes_virtualSpectrum.XLim = [0 xLimit];
            app.UIAxes_powerSpectrum.XLim = [0 xLimit];
            app.UIAxes_amplitudeSpectrum.XLim = [0 xLimit];
            app.UIAxes_phaseSpectrum.XLim = [0 xLimit];
            mainPlot(app);
            showFeature(app,app.Signal1);
            plotFeature(app,app.UIAxes_main,app.Signal1);
            plotPhase(app,app.Signal1);
            plotPower(app,app.Signal1);
            plotReal(app,app.Signal1);
            plotImag(app,app.Signal1);
        end
        
        function mainPlot(app)
              plotSignal1(app);
              plotAmplitude(app,app.Signal1);

        end
        
        function plotSignal1(app)
            app.T = app.N/app.Fs;
            D = app.InputTable.Data;
            m =size(D,1);
            x = linspace(0,app.T,app.N);
            y = D(1,2)*sin(2*pi*D(1,1)*x+D(1,3));
            if m>1
                for i = 2:m
                    y = y + D(i,2)*sin(2*pi*D(i,1)*x+D(i,3));
                end
            end

            app.X = x;
            app.Signal1.Y = y;
            app.Signal1 = refreshSignalParameters(app,app.Signal1);
            plot(app.UIAxes_main,app.X,app.Signal1.Y);

        end
        
        function Signal = refreshSignalParameters(app,Signal)
            y = Signal.Y;
            Signal.Vp = max(y);
            Signal.Vv = min(y);
            Signal.Vpp =  Signal.Vp - Signal.Vv;
            Signal.AverageValue = mean(y);
            Signal.EffectiveValue = norm(y)/sqrt(length(y));

            
        end
           
        function showFeature(app,Signal)
            av = Signal.AverageValue;
            app.showAverageValue.Value = av;
            app.showVp.Value = Signal.Vp;
            app.showEffectiveValue.Value = Signal.EffectiveValue;
            app.showVpp.Value = Signal.Vpp;
        end
        
        function plotFeature(app,Axex,Signal)
            if app.isShowFeature
                app.T = app.N/app.Fs;
                x = [0,app.T];
                hold (Axex,'on');
                plot(Axex,x,Signal.Vp.*ones(1,2));
                %plot(Axex,x,Signal.Vpp.*ones(1,2));
                plot(Axex,x,Signal.Vv.*ones(1,2));
                plot(Axex,x,Signal.AverageValue.*ones(1,2));
                plot(Axex,x,Signal.EffectiveValue.*ones(1,2));
                hold (Axex,'off');
            end
            
        end
        
        function plotAmplitude(app,Signal)
            C = fft(Signal.Y,app.N); %得Cn
            A = 2*sqrt(C.*conj(C))/app.N; %求An
            A(1) = A(1)/2;
            f = linspace(0,app.Fs/2,app.N/2);
            plot(app.UIAxes_amplitudeSpectrum,f,A(1:app.N/2));
            
        end
        
        function plotPhase(app,Signal)
            yy = fft(Signal.Y,app.N);

            angleX = 2*angle(yy(1:app.N/2))*180/pi;
            f = linspace(0,app.Fs/2,app.N/2);
            plot(app.UIAxes_phaseSpectrum,f,angleX);
        end
        
        function plotPower(app,Signal)
            yy = fft(Signal.Y,app.N);
            P = 2*sqrt(yy.*conj(yy))/app.N;
            P(1) = P(1)/2;
            P = P.^2;
            f = linspace(0,app.Fs/2,app.N/2);
            plot(app.UIAxes_powerSpectrum,f,P(1:app.N/2));
        end
        
        function plotReal(app,Signal)
            f = linspace(0,app.Fs/2,app.N/2);
            yy = fft(Signal.Y,app.N);
            plot(app.UIAxes_realSpectrum,f,real(yy(1:app.N/2)));
            
        end
        
        function plotImag(app,Signal)
            f = linspace(0,app.Fs/2,app.N/2);
            yy = fft(Signal.Y,app.N);
            plot(app.UIAxes_virtualSpectrum,f,imag(yy(1:app.N/2)));
            
        end
        
        function readCardSignal(app)
%             data = USBDAQ_readdata(app.Fs,app.N,bin2dec('0001 0000'));
%             app.Signal2.Y =data(:,5)';
            y = randn(1,app.N);
            app.Signal2.Y = y;
            app.Signal2 = refreshSignalParameters(app,app.Signal2);
        end
        
        function plotCardSignal(app)
            x = linspace(0,app.T,app.N);
            plot(app.UIAxes_signal2,x,app.Signal2.Y);
        end
        
        function counter = TDZCD(app,y)
        %Time domain zero crossing detection时域过零检测算法
            counter =0;
            for i=1:app.N-1
                if y(i)*y(i+1)< 0
                    counter = counter+1;
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            a = [50,2,pi/6;
                200,1,pi/3;
                100,3,pi/2];
            
            app.samplingRate.Value = 5120;
            app.Fs = app.samplingRate.Value;
            app.samplingExtent.Value = 1000;
            app.N = app.samplingExtent.Value;
            app.T = app.N/app.Fs;
            app.InputTable.Data  = a;
            %refreshSignalParameters(app,Signal)
%             %%设置坐标范围
%             xLimit = max(a(:,1))*1.5;
%             app.UIAxes_realSpectrum.XLim = [0 xLimit];
%             app.UIAxes_virtualSpectrum.XLim = [0 xLimit];
%             app.UIAxes_powerSpectrum.XLim = [0 xLimit];
%             app.UIAxes_amplitudeSpectrum.XLim = [0 xLimit];
%             app.UIAxes_phaseSpectrum.XLim = [0 xLimit];
            Show(app)
            app.Switch_Card.ItemsData = {0,1};
            app.whiteNoiseA.Value = 1;
            app.Lamp_Card.Color = 'red';
        end

        % Button pushed function: Button_init
        function Button_initPushed(app, event)
            startupFcn(app);
        end

        % Value changed function: samplingRate
        function samplingRateValueChanged(app, event)
            v = app.samplingRate.Value;
            app.Fs = round(v) ;
            Show(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {811, 811, 811};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {811, 811};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {254, '1x', 345};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: samplingExtent
        function samplingExtentValueChanged(app, event)
           app.N = app.samplingExtent.Value;
           Show(app)            
        end

        % Cell edit callback: InputTable
        function InputTableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            Show(app)
        end

        % Value changed function: Button_showFeature
        function Button_showFeatureValueChanged(app, event)
            app.isShowFeature = app.Button_showFeature.Value;
            Show(app)            
        end

        % Button pushed function: Button_autocorrelation
        function Button_autocorrelationPushed(app, event)
            s = xcorr(app.Signal1.Y,app.Signal1.Y,'coeff');
            x1 = linspace(0,2*app.T,2*app.N-1);
            plot(app.UIAxes_result,x1,s)
        end

        % Value changed function: Switch_Card
        function Switch_CardValueChanged(app, event)
            value = app.Switch_Card.Value;
            if (value == 0)
                app.Lamp_Card.Color = 'red';
                app.CardState  = 0;
                %USBDAQ_closecard();
            elseif  (value == 1)
                %app.CardState = USBDAQ_opencard();
                app.CardState  = 1;
                app.Lamp_Card.Color = 'green';
                while (app.CardState == 1)
                     readCardSignal(app);
                     plotCardSignal(app);
%                    app.CardData=USBDAQ_readdata(app.N,app.Fs,bin2dec('0001 0000'));
                     pause(1);
                end               
            end
        end

        % Button pushed function: Button_getWhiteNoise
        function Button_getWhiteNoisePushed(app, event)
            app.Signal3.Y = app.whiteNoiseA.Value.*randn(1,app.N);
            plot(app.UIAxes_signal2,app.X,app.Signal3.Y);
        end

        % Value changed function: whiteNoiseA
        function whiteNoiseAValueChanged(app, event)
            Button_getWhiteNoisePushed(app);
        end

        % Button pushed function: superpositionWithSignal1
        function superpositionWithSignal1ButtonPushed(app, event)
            app.Superposition.Y =  app.Signal1.Y + app.Signal3.Y;
            plot(app.UIAxes_superposition,app.X,app.Superposition.Y);
        end

        % Button pushed function: whiteNoiseAutocorrelation
        function whiteNoiseAutocorrelationButtonPushed(app, event)
            s = xcorr(app.Signal3.Y,app.Signal3.Y,'coeff');
            x1 = linspace(0,2*app.T,2*app.N-1);
            plot(app.UIAxes_result,x1,s)
        end

        % Button pushed function: correlationWithSignal1
        function correlationWithSignal1ButtonPushed(app, event)
            s = xcorr(app.Signal1.Y,app.Signal3.Y,'coeff');
            x1 = linspace(0,2*app.T,2*app.N-1);
            plot(app.UIAxes_result,x1,s)
        end

        % Button pushed function: superpositionAutocorrelation
        function superpositionAutocorrelationPushed(app, event)
            s = xcorr(app.Superposition.Y,app.Superposition.Y,'coeff');
            x1 = linspace(0,2*app.T,2*app.N-1);
            plot(app.UIAxes_result,x1,s)
        end

        % Button pushed function: getSquare
        function getSquareButtonPushed(app, event)

            y=app.squareA.Value.*square(2*pi*app.squareF.Value*app.X,app.squareDuty.Value);
            hold(app.UIAxes_signal2,'on');
            plot(app.UIAxes_signal2,[0,app.T],[0 0]);
            plot(app.UIAxes_signal2,app.X,y);
            hold(app.UIAxes_signal2,'off');
            %app.squareFtested = TDZCD(app,y);
            counter =0;
            for i=1:app.N-1
                if y(i)*y(i+1)< 0
                    counter = counter+1;
                end
            end
            app.squareFtested.Value = counter/(2*app.T);
        end

        % Value changed function: squareA
        function squareAValueChanged(app, event)

            getSquareButtonPushed(app);
        end

        % Value changed function: squareF
        function squareFValueChanged(app, event)
            getSquareButtonPushed(app);
            
        end

        % Value changed function: squareDuty
        function squareDutyValueChanged(app, event)
           
            getSquareButtonPushed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputTable = uitable(app.LeftPanel);
            app.InputTable.ColumnName = {'频率(Hz)'; '幅值'; '初相位'};
            app.InputTable.RowName = {'1'; '2'; '3'};
            app.InputTable.ColumnEditable = true;
            app.InputTable.CellEditCallback = createCallbackFcn(app, @InputTableCellEdit, true);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '基本信息';
            app.Button_init = uibutton(app.LeftPanel, 'push');
            app.Button_init.ButtonPushedFcn = createCallbackFcn(app, @Button_initPushed, true);
            app.Button_init.Text = '初始化';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '采样频率';
            app.samplingRate = uieditfield(app.LeftPanel, 'numeric');
            app.samplingRate.Limits = [0 Inf];
            app.samplingRate.RoundFractionalValues = 'on';
            app.samplingRate.ValueChangedFcn = createCallbackFcn(app, @samplingRateValueChanged, true);
            app.samplingRate.Value = 5120;
            app.EditField_2Label = uilabel(app.LeftPanel);
            app.EditField_2Label.Text = '采样长度';
            app.samplingExtent = uieditfield(app.LeftPanel, 'numeric');
            app.samplingExtent.ValueChangedFcn = createCallbackFcn(app, @samplingExtentValueChanged, true);
            app.samplingExtent.Value = 1000;
            app.Button_autocorrelation = uibutton(app.LeftPanel, 'push');
            app.Button_autocorrelation.ButtonPushedFcn = createCallbackFcn(app, @Button_autocorrelationPushed, true);
            app.Button_autocorrelation.Text = '自相关分析';
            app.TabGroup3 = uitabgroup(app.LeftPanel);
            app.Tab_signal2Analysis = uitab(app.TabGroup3);
            app.Tab_signal2Analysis.Title = '采集卡';
            app.Label_7 = uilabel(app.Tab_signal2Analysis);
            app.Label_7.Text = '采集卡状态';
            app.Lamp_Card = uilamp(app.Tab_signal2Analysis);
            app.Switch_Card = uiswitch(app.Tab_signal2Analysis, 'slider');
            app.Switch_Card.ValueChangedFcn = createCallbackFcn(app, @Switch_CardValueChanged, true);
            app.Tab_5 = uitab(app.TabGroup3);
            app.Tab_5.Title = '白噪声';
            app.Button_getWhiteNoise = uibutton(app.Tab_5, 'push');
            app.Button_getWhiteNoise.ButtonPushedFcn = createCallbackFcn(app, @Button_getWhiteNoisePushed, true);
            app.Button_getWhiteNoise.Text = '采集信号';
            app.Label_8 = uilabel(app.Tab_5);
            app.Label_8.Text = '大小';
            app.whiteNoiseA = uieditfield(app.Tab_5, 'numeric');
            app.whiteNoiseA.ValueChangedFcn = createCallbackFcn(app, @whiteNoiseAValueChanged, true);
            app.whiteNoiseA.Value = 1;
            app.whiteNoiseAutocorrelation = uibutton(app.Tab_5, 'push');
            app.whiteNoiseAutocorrelation.ButtonPushedFcn = createCallbackFcn(app, @whiteNoiseAutocorrelationButtonPushed, true);
            app.whiteNoiseAutocorrelation.Text = '自相关分析';
            app.superpositionWithSignal1 = uibutton(app.Tab_5, 'push');
            app.superpositionWithSignal1.ButtonPushedFcn = createCallbackFcn(app, @superpositionWithSignal1ButtonPushed, true);
            app.superpositionWithSignal1.Text = '信号叠加';
            app.correlationWithSignal1 = uibutton(app.Tab_5, 'push');
            app.correlationWithSignal1.ButtonPushedFcn = createCallbackFcn(app, @correlationWithSignal1ButtonPushed, true);
            app.correlationWithSignal1.Text = '互相关分析';
            app.superpositionAutocorrelation = uibutton(app.Tab_5, 'push');
            app.superpositionAutocorrelation.ButtonPushedFcn = createCallbackFcn(app, @superpositionAutocorrelationPushed, true);
            app.superpositionAutocorrelation.Text = '叠加信号自相关分析';
            app.Tab_signal3Analysis = uitab(app.TabGroup3);
            app.Tab_signal3Analysis.Title = '方波';
            app.getSquare = uibutton(app.Tab_signal3Analysis, 'push');
            app.getSquare.ButtonPushedFcn = createCallbackFcn(app, @getSquareButtonPushed, true);
            app.getSquare.Text = '采集型号';
            app.Label_9 = uilabel(app.Tab_signal3Analysis);
            app.Label_9.Text = '幅值';
            app.squareA = uieditfield(app.Tab_signal3Analysis, 'numeric');
            app.squareA.ValueChangedFcn = createCallbackFcn(app, @squareAValueChanged, true);
            app.squareA.Value = 1;
            app.Label_10 = uilabel(app.Tab_signal3Analysis);
            app.Label_10.Text = '频率';
            app.squareF = uieditfield(app.Tab_signal3Analysis, 'numeric');
            app.squareF.ValueChangedFcn = createCallbackFcn(app, @squareFValueChanged, true);
            app.squareF.Value = 50;
            app.Label_11 = uilabel(app.Tab_signal3Analysis);
            app.Label_11.Text = '空占比';
            app.squareDuty = uieditfield(app.Tab_signal3Analysis, 'numeric');
            app.squareDuty.ValueChangedFcn = createCallbackFcn(app, @squareDutyValueChanged, true);
            app.squareDuty.Value = 50;
            app.Label_12 = uilabel(app.Tab_signal3Analysis);
            app.Label_12.Text = '时域过零检测';
            app.squareFtested = uieditfield(app.Tab_signal3Analysis, 'numeric');
            app.squareFtested.Editable = 'off';
            app.U201810832Label = uilabel(app.LeftPanel);
            app.U201810832Label.Text = '李聪 U201810832';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '峰峰值';
            app.showVpp = uieditfield(app.LeftPanel, 'numeric');
            app.showVpp.Editable = 'off';
            app.EditField_2Label_2 = uilabel(app.LeftPanel);
            app.EditField_2Label_2.Text = '峰值';
            app.showVp = uieditfield(app.LeftPanel, 'numeric');
            app.showVp.Editable = 'off';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '有效值';
            app.showEffectiveValue = uieditfield(app.LeftPanel, 'numeric');
            app.showEffectiveValue.Editable = 'off';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '平均值';
            app.showAverageValue = uieditfield(app.LeftPanel, 'numeric');
            app.showAverageValue.Editable = 'off';
            app.Button_showFeature = uibutton(app.LeftPanel, 'state');
            app.Button_showFeature.ValueChangedFcn = createCallbackFcn(app, @Button_showFeatureValueChanged, true);
            app.Button_showFeature.Text = '绘制特征';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '主信号';
            app.UIAxes_main = uiaxes(app.Tab);
            title(app.UIAxes_main, '信号输出')
            xlabel(app.UIAxes_main, 't')
            ylabel(app.UIAxes_main, 'Y')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '采集信号';
            app.UIAxes_signal2 = uiaxes(app.Tab_2);
            title(app.UIAxes_signal2, '采集信号')
            xlabel(app.UIAxes_signal2, 't')
            ylabel(app.UIAxes_signal2, 'Y')
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '信号叠加';
            app.UIAxes_superposition = uiaxes(app.Tab_6);
            title(app.UIAxes_superposition, '叠加信号')
            xlabel(app.UIAxes_superposition, 't')
            ylabel(app.UIAxes_superposition, 'Y')
            app.UIAxes_result = uiaxes(app.CenterPanel);
            title(app.UIAxes_result, '分析结果')
            xlabel(app.UIAxes_result, 't')
            ylabel(app.UIAxes_result, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_powerSpectrum = uiaxes(app.RightPanel);
            title(app.UIAxes_powerSpectrum, '功率谱')
            xlabel(app.UIAxes_powerSpectrum, '频率(Hz)')
            ylabel(app.UIAxes_powerSpectrum, 'P')
            app.UIAxes_amplitudeSpectrum = uiaxes(app.RightPanel);
            title(app.UIAxes_amplitudeSpectrum, '幅值谱')
            xlabel(app.UIAxes_amplitudeSpectrum, '频率(Hz)')
            ylabel(app.UIAxes_amplitudeSpectrum, 'An')
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '实频谱';
            app.UIAxes_realSpectrum = uiaxes(app.Tab_3);
            title(app.UIAxes_realSpectrum, '')
            xlabel(app.UIAxes_realSpectrum, '频率(Hz)')
            ylabel(app.UIAxes_realSpectrum, '[Re]')
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '虚频谱';
            app.UIAxes_virtualSpectrum = uiaxes(app.Tab_4);
            title(app.UIAxes_virtualSpectrum, '')
            xlabel(app.UIAxes_virtualSpectrum, '频率(Hz)')
            ylabel(app.UIAxes_virtualSpectrum, '[Im]')
            app.UIAxes_phaseSpectrum = uiaxes(app.RightPanel);
            title(app.UIAxes_phaseSpectrum, '相位谱')
            xlabel(app.UIAxes_phaseSpectrum, '频率(Hz)')
            ylabel(app.UIAxes_phaseSpectrum, '相位角(°)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end